classdef HXApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TeensyConnectionSettingsPanel  matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DefaultconnectionsettingsCheckBox  matlab.ui.control.CheckBox
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        DebugTextArea                  matlab.ui.control.TextArea
        DebugTextAreaLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        iTextBox        % Line in debug textbox
        configuration   % Variables relating to current configuration
        variables       % Misc variables
        teensy          % Teensy object
        debugFile       % Debug file
    end
    
    methods (Access = private)
             
        function getSerialPortList(app)
            displayDebug(app, 'Fetching COM settings');
            serialPorts = serialportlist("available");
            list = ["Select"];
            for i=1:length(serialPorts)
                list(end+1) = serialPorts(i); %#ok<AGROW> 
            end
            app.configuration.comList       = list;
            app.configuration.baudList      = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
            app.BaudrateDropDown.Items      = app.configuration.baudList;
            app.COMPortDropDown.Items       = app.configuration.comList;
            displayDebug(app, 'Fetched COM settings');
        end
        
        function displayDebug(app, message)
            fullMessage = strjoin({datestr(now,'HH:MM:SS.FFF') ':' message});
            % Display to the dialogue box in the app
            app.iTextBox = app.iTextBox + 1;
            app.DebugTextArea.Value(app.iTextBox) = {fullMessage};
            % Save to debugFile property for final debug file
            app.debugFile.text(app.iTextBox) = {fullMessage};
        end
        
        function initializeTeensyConnection(app, comPort, Baudrate)
            displayDebug(app, 'Starting Teensy initialization');
            app.configuration.comPort   = comPort;
            app.configuration.BAUD      = Baudrate;
            app.teensy = serialport(comPort, baudrate);
            configureTerminator(app.teensy, "CR/LF");
            flush(app.teensy);
            displayDebug(app, 'Successfully initialized Teensy');
            app.COMPortDropDown.Value   = comPort;
            app.BaudrateDropDown.Value  = num2str(Baudrate);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear; clc; close all;
            app.iTextBox = 0;
            app.variables.noTeensyConnectColor      = [0.95 0.39 0.39];
            app.variables.redColor                  = [1 0 0];
            app.variables.defaultBackgroundColor    = [0.94 0.94 0.94];
            
            % Set teensy connect panel background color
            app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.noTeensyConnectColor;
            
            % Load the last variables saved in the configuration.mat file
            displayDebug(app, 'Loading configuration.mat');
            load configuration.mat BAUD comPort plotSeconds plotUpdateTime readVarCount readVarType sendVarCount sendVarType;
            % Save the variables from configuration.mat in the  
            % configuration property
            app.configuration.comPort           = comPort;
            app.configuration.BAUD              = BAUD;
            app.configuration.readVarCount      = readVarCount;
            app.configuration.sendVarCount      = sendVarCount;
            app.configuration.readVarType       = readVarType;
            app.configuration.sendVarType       = sendVarType;
            app.configuration.plotSeconds       = plotSeconds;        %seconds
            app.configuration.plotUpdateTime    = plotUpdateTime;     %seconds
            displayDebug(app, 'Loaded configuration.mat')
            
            % Create timer for plot update
            
            
            % Get serial port lists
            getSerialPortList(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Check if the variables are valid
            comPort = app.COMPortDropDown.Value;
            baudRate = app.BaudrateDropDown.Value;
            goodComPort = false;
            goodBaudRate = false;

            if (any(strcmp(app.configuration.comList, comPort)))
                goodComPort = true;
            end
            if ~(strcmp("Select", baudRate))
                goodBaudRate = true;
                baudRate = str2num(baudRate); %#ok<ST2NM>
            end

            % Send data to the main app if good
            if (goodComPort && goodBaudRate)
                message = strjoin({'using' comPort '@' int2str(baudRate)});
                displayDebug(app, message);
                try
                    initializeTeensyConnection(app, comPort, baudRate);
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.defaultBackgroundColor;
                    app.ConnectButton.Enable = 0;       % Disable connect button
                catch
                    displayDebug(app, 'Fatal error with Teensy init');
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.noTeensyConnectColor;
                end
            else
                displayDebug(app, 'Connection settings illegal');                
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            getSerialPortList(app);
        end

        % Value changed function: DefaultconnectionsettingsCheckBox
        function DefaultconnectionsettingsCheckBoxValueChanged(app, event)
            value = app.DefaultconnectionsettingsCheckBox.Value;
            if value == 1
                % Check if the default COM port is in current list, if not dispaly error
                comPort = app.configuration.comPort;
                if ~(any(strcmp(app.configuration.comList, comPort)))
                    displayDebug(app, 'Default COM port is not available');
                    app.UseDefaultConnectionSettingsCheckBox = 0;
                else
                    app.BaudrateDropDown.Value    = num2str(app.configuration.BAUD);
                    app.COMPortDropDown.Value     = app.configuration.comPort;
                    displayDebug(app, 'Valid default settings');
                end
            else
                getSerialPortList(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {643, 643, 643};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {643, 643};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 226};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TeensyConnectionSettingsPanel = uipanel(app.LeftPanel);
            app.TeensyConnectionSettingsPanel.Title = 'Teensy Connection Settings';
            app.COMPortDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.DefaultconnectionsettingsCheckBox = uicheckbox(app.TeensyConnectionSettingsPanel);
            app.DefaultconnectionsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultconnectionsettingsCheckBoxValueChanged, true);
            app.DefaultconnectionsettingsCheckBox.Text = 'Default connection settings';
            app.RefreshButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.DebugTextAreaLabel = uilabel(app.RightPanel);
            app.DebugTextAreaLabel.Text = {'Debug'; ''};
            app.DebugTextArea = uitextarea(app.RightPanel);
            app.DebugTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HXApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end